classdef Coursework < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Task1Button                   matlab.ui.control.Button
        NumberofSquaresSpinnerLabel   matlab.ui.control.Label
        NumberofSquaresSpinner        matlab.ui.control.Spinner
        LengthofSquaresSpinnerLabel   matlab.ui.control.Label
        LengthofSquaresSpinner        matlab.ui.control.Spinner
        WidthofPlankSpinnerLabel      matlab.ui.control.Label
        WidthofPlankSpinner           matlab.ui.control.Spinner
        ShowTask1Button               matlab.ui.control.Button
        ShowTask2Button               matlab.ui.control.Button
        Task2Button                   matlab.ui.control.Button
        NumberofSquares2SpinnerLabel  matlab.ui.control.Label
        NumberofSquares2Spinner       matlab.ui.control.Spinner
        LengthofSquares2SpinnerLabel  matlab.ui.control.Label
        LengthofSquares2Spinner       matlab.ui.control.Spinner
        WidthofPlank2SpinnerLabel     matlab.ui.control.Label
        WidthofPlank2Spinner          matlab.ui.control.Spinner
        ShowTask3Button               matlab.ui.control.Button
        Task3Button                   matlab.ui.control.Button
        NumberofNeedlesSpinnerLabel   matlab.ui.control.Label
        NumberofNeedlesSpinner        matlab.ui.control.Spinner
        LengthofNeedlesSpinnerLabel   matlab.ui.control.Label
        LengthofNeedlesSpinner        matlab.ui.control.Spinner
        WidthofPlanksSpinnerLabel     matlab.ui.control.Label
        WidthofPlanksSpinner          matlab.ui.control.Spinner
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = func(app)
            ax = app.UIAxes;
            axes(ax); % Make averSpec the current axes.
            cla reset; % Do a complete and total reset of the axes.
            
            ax.Visible = 'off';
            app.NumberofSquaresSpinner.Visible = 'off';
            app.NumberofSquaresSpinnerLabel.Visible = 'off';
            app.LengthofSquaresSpinner.Visible = 'off';
            app.LengthofSquaresSpinnerLabel.Visible = 'off';
            app.WidthofPlankSpinner.Visible = 'off';
            app.WidthofPlankSpinnerLabel.Visible = 'off';
            
            ax2 = app.UIAxes;
            axes(ax2); % Make averSpec the current axes.
            cla reset; % Do a complete and total reset of the axes.
            
            ax2.Visible = 'off';
            app.NumberofSquares2Spinner.Visible = 'off';
            app.NumberofSquares2SpinnerLabel.Visible = 'off';
            app.LengthofSquares2Spinner.Visible = 'off';
            app.LengthofSquares2SpinnerLabel.Visible = 'off';
            app.WidthofPlank2Spinner.Visible = 'off';
            app.WidthofPlank2SpinnerLabel.Visible = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          func(app);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           
        end

        % Button pushed function: Task1Button
        function Task1ButtonPushed(app, event)
            cla reset;
            clear app.UIAxes;
            
            % Creates variable to control axes 
            ax = app.UIAxes;
  
            % Number of squares
            N = app.NumberofSquaresSpinner.Value;
            
            % Length of needle
            L = app.LengthofSquaresSpinner.Value;
            
            % Length of planks 
            P = app.WidthofPlankSpinner.Value;
            
            % Drawing lines
            y = 0:P:1;
            
            % Plotting floorboards
            for x = L:P:(1 - L) 
                 hold on
                 plot(ax, x*ones(size(y)),y, 'k');
            end
            
            % Used to calculate PI
            count = 0;
            
            for num = 1:N
                needleX = rand(1) * (1 - 2*L);
            
                 if needleX > (1 - L)
                     needleX = needleX - L;
                 end
             
                 if needleX < L
                     needleX = needleX + L;
                 end
            
                % Get starting Y value for Needle
                needleY = rand(1) * (1 - 2*L);
            
                 if needleY > (1 - L)
                     needleY = needleY - L;
                 end
             
                 if needleY < L
                     needleY = needleY + L;
                 end
            
                % Get Angle for needle
                needleAng = rand(1) * 360;
            
                % Get ending X value for Needle
                needleX2 = needleX  + (L * cosd(needleAng));
            
                % Get ending Y value for Needle
                needleY2 = needleY + (L * sind(needleAng));
            
            
                % Get start of needle perpendicular to needleX
                needleX3 = needleX + (L * cosd(needleAng + 90));
            
                % Get start of needle perpendicular to needleY
                needleY3 = needleY + (L * sind(needleAng + 90));
            
                % Get start of needle perpendicular to needleX2
                needleX4 = needleX2 + (L * cosd(needleAng + 90));
            
                % Get start of needle perpendicular to needleY2
                needleY4 = needleY2 + (L * sind(needleAng + 90));
            
                % Reset condtions
                check = 0;
                
                for x = L:P:(1 - L)  
                    
                    
                    % Check if first needle crosses a line
                    if ((needleX < x) && (needleX2 > x)) || ((needleX > x) && (needleX2 < x))
                        check = 1;
                        if num < 500
                            plot(ax, [needleX; needleX2], [needleY; needleY2], 'g');
                            plot(ax, [needleX; needleX3], [needleY; needleY3], 'g');
                            plot(ax, [needleX2; needleX4], [needleY2; needleY4], 'g');
                            plot(ax, [needleX3; needleX4], [needleY3; needleY4], 'g'); 
                        end
                        count = count + 1;
                    end
                    
                    
                    % Check if second needle crosses a line
                    if ((needleX < x) && (needleX3 > x)) || ((needleX > x) && (needleX3 < x))
                        check = 1;
                        if num < 500 && check == 0
                             plot(ax, [needleX; needleX2], [needleY; needleY2], 'g');
                             plot(ax, [needleX; needleX3], [needleY; needleY3], 'g');
                             plot(ax, [needleX2; needleX4], [needleY2; needleY4], 'g');
                             plot(ax, [needleX3; needleX4], [needleY3; needleY4], 'g'); 
                        end
                        count = count + 1;
                    end
                    
                    
                    % Check if third needle crosses a line
                    if ((needleX2 < x) && (needleX4 > x)) || ((needleX2 > x) && (needleX4 < x))
                        check = 1;
                        if num < 500 && check == 0
                            plot(ax, [needleX; needleX2], [needleY; needleY2], 'g');
                            plot(ax, [needleX; needleX3], [needleY; needleY3], 'g');
                            plot(ax, [needleX2; needleX4], [needleY2; needleY4], 'g');
                            plot(ax, [needleX3; needleX4], [needleY3; needleY4], 'g'); 
                        end
                        count = count + 1;
                    end 
                    
                    
                    % Check if fourth needle crosses a line
                    if ((needleX3 < x) && (needleX4 > x)) || ((needleX3 > x) && (needleX4 < x))
                        check = 1;
                        if num < 500 && check == 0
                            plot(ax, [needleX; needleX2], [needleY; needleY2], 'g');
                            plot(ax, [needleX; needleX3], [needleY; needleY3], 'g');
                            plot(ax, [needleX2; needleX4], [needleY2; needleY4], 'g');
                            plot(ax, [needleX3; needleX4], [needleY3; needleY4], 'g'); 
                        end
                        count = count + 1;
                    end 
                end
                
                
                if check == 0 && num < 500
                    plot(ax, [needleX; needleX2], [needleY; needleY2], 'k');
                    plot(ax, [needleX; needleX3], [needleY; needleY3], 'k');
                    plot(ax, [needleX2; needleX4], [needleY2; needleY4], 'k');
                    plot(ax, [needleX3; needleX4], [needleY3; needleY4], 'k'); 
                end
                
            end
            
            pie = (N * 4) / count;
            
            ax.Title.String = string(pie);
            axis square
        end

        % Button pushed function: ShowTask1Button
        function ShowTask1ButtonPushed(app, event)
            func(app);
            
            app.UIAxes.Visible = 'on';
            app.NumberofSquaresSpinner.Visible = 'on';
            app.NumberofSquaresSpinnerLabel.Visible = 'on';
            app.LengthofSquaresSpinner.Visible = 'on';
            app.LengthofSquaresSpinnerLabel.Visible = 'on';
            app.WidthofPlankSpinner.Visible = 'on';
            app.WidthofPlankSpinnerLabel.Visible = 'on';
        end

        % Button pushed function: Task2Button
        function Task2ButtonPushed(app, event)
            ax2 = app.UIAxes2;
            
            % Number of squares
            N = app.NumberofSquares2Spinner.Value;
            
            % Length of needle
            L = app.LengthofSquares2Spinner.Value;
            
            % Drawing lines
            y = 0:0.2:1;
            
            % Plotting floorboards
            for x = L:(2 * L):(1 - L) 
                 hold on
                 plot(ax2, x*ones(size(y)),y, 'k');
            end
            
            % Counters
            Nc = 0;
            Ncc = 0;
            
            for num = 1:N
                needleX = rand(1) * (1 - 2*L);
            
                if needleX > (1 - L)
                    needleX = needleX - L;
                end
             
                if needleX < L
                    needleX = needleX + L;
                end
                
                % Get starting Y value for Needle
                needleY = rand(1) * (1 - 2*L);
                
                if needleY > (1 - L)
                    needleY = needleY - L;
                end
                
                if needleY < L
                    needleY = needleY + L;
                end
            
                % Get Angle for needle
                needleAng = rand(1) * 360;
            
                % Get ending X value for Needle
                needleX2 = needleX  + (L * cosd(needleAng));
            
                % Get ending Y value for Needle
                needleY2 = needleY + (L * sind(needleAng));
            
                % Get start of needle perpendicular to needleX
                needleX3 = needleX + (L * cosd(needleAng + 90));
            
                % Get start of needle perpendicular to needleY
                needleY3 = needleY + (L * sind(needleAng + 90));
            
                % Get start of needle perpendicular to needleX2
                needleX4 = needleX2 + (L * cosd(needleAng + 90));
            
                % Get start of needle perpendicular to needleY2
                needleY4 = needleY2 + (L * sind(needleAng + 90));
            
                % Reset condtions
                check1 = 0;
                check2 = 0;
                check3 = 0;
                check4 = 0;
                
                for x = L:(2 * L):(1 - L)  
                    
                    
                    % Check if first needle crosses a line
                    if ((needleX < x) && (needleX2 > x)) || ((needleX > x) && (needleX2 < x))
                        check1 = 1;
                        if num < 500
                            plot(ax2, [needleX; needleX2], [needleY; needleY2], 'g');
                            plot(ax2, [needleX; needleX3], [needleY; needleY3], 'g');
                            plot(ax2, [needleX2; needleX4], [needleY2; needleY4], 'g');
                            plot(ax2, [needleX3; needleX4], [needleY3; needleY4], 'g');
                        end
                    end
                    
                    
                    % Check if second needle crosses a line
                    if ((needleX < x) && (needleX3 > x)) || ((needleX > x) && (needleX3 < x))
                        check2 = 1;
                        if num < 500
                             plot(ax2, [needleX; needleX2], [needleY; needleY2], 'g');
                             plot(ax2, [needleX; needleX3], [needleY; needleY3], 'g');
                             plot(ax2, [needleX2; needleX4], [needleY2; needleY4], 'g');
                             plot(ax2, [needleX3; needleX4], [needleY3; needleY4], 'g'); 
                        end
                    end
                    
                    
                    % Check if third needle crosses a line
                    if ((needleX2 < x) && (needleX4 > x)) || ((needleX2 > x) && (needleX4 < x))
                        check3 = 1;
                        if num < 500
                            plot(ax2, [needleX; needleX2], [needleY; needleY2], 'g');
                            plot(ax2, [needleX; needleX3], [needleY; needleY3], 'g');
                            plot(ax2, [needleX2; needleX4], [needleY2; needleY4], 'g');
                            plot(ax2, [needleX3; needleX4], [needleY3; needleY4], 'g'); 
                        end
                    end 
                    
                    
                    % Check if fourth needle crosses a line
                    if ((needleX3 < x) && (needleX4 > x)) || ((needleX3 > x) && (needleX4 < x))
                        check4 = 1;
                        if num < 500
                            plot(ax2, [needleX; needleX2], [needleY; needleY2], 'g');
                            plot(ax2, [needleX; needleX3], [needleY; needleY3], 'g');
                            plot(ax2, [needleX2; needleX4], [needleY2; needleY4], 'g');
                            plot(ax2, [needleX3; needleX4], [needleY3; needleY4], 'g'); 
                        end
                    end
                end
                
                % Check if line intersects two consecutive sides
                if check1 == 1 && check2 == 1
                    Ncc = Ncc + 1;
                end
                
                if check1 == 1 && check3 == 1
                    Ncc = Ncc + 1;
                end
                
                if check3 == 1 && check4 == 1
                    Ncc = Ncc + 1;
                end
                
                if check2 == 1 && check4 == 1
                    Ncc = Ncc + 1;
                end
                
                if check1 == 0 && check2 == 0 && check3 == 0 && check4 == 0
                    if num < 500
                        plot(ax2, [needleX; needleX2], [needleY; needleY2], 'k');
                        plot(ax2, [needleX; needleX3], [needleY; needleY3], 'k');
                        plot(ax2, [needleX2; needleX4], [needleY2; needleY4], 'k');
                        plot(ax2, [needleX3; needleX4], [needleY3; needleY4], 'k');
                    end
                else
                    Nc = Nc + 1;
                    if num < 500
                        plot(ax2, [needleX; needleX2], [needleY; needleY2], 'g');
                        plot(ax2, [needleX; needleX3], [needleY; needleY3], 'g');
                        plot(ax2, [needleX2; needleX4], [needleY2; needleY4], 'g');
                        plot(ax2, [needleX3; needleX4], [needleY3; needleY4], 'g');
                    end
                end
                  
            end
            
            root2 = 2 - (Ncc / Nc);
            
            ax2.Title.String = string(root2);
            axis square
        end

        % Button pushed function: ShowTask2Button
        function ShowTask2ButtonPushed(app, event)
            func(app);
            
            app.UIAxes2.Visible = 'on';
            app.NumberofSquaresSpinner.Visible = 'on';
            app.NumberofSquares2SpinnerLabel.Visible = 'on';
            app.LengthofSquaresSpinner.Visible = 'on';
            app.LengthofSquares2SpinnerLabel.Visible = 'on';
            app.WidthofPlankSpinner.Visible = 'on';
            app.WidthofPlankSpinnerLabel.Visible = 'on';
        end

        % Button pushed function: Task3Button
        function Task3ButtonPushed(app, event)
            ax3 = app.UIAxes3;

            % Number of needles
            N = app.NumberofNeedlesSpinner.Value;
            
            % Length of needles
            L = app.LengthofNeedlesSpinner.Value;
            
            % Drawing vertical lines
            y = L:(2 * L):1 - L;
            
            % Drawing horizontal lines
            x = L:(2 * L):1 - L;
            
            % Plotting vertical lines
            for xVal = L:(2 * L):1 - L
                 hold on
                 plot(ax3, xVal*ones(size(y)),y, 'k');
            end
            
            % Plotting vertical lines
            for yVal = L:(2 * L):1 - L
                 hold on
                 plot(ax3, x, yVal*ones(size(x)), 'k');
            end
            
            % Used to calculate PI
            count = 0;
            
            
            for num = 1:N
                % Get starting X value for Needle
                needleX = rand(1) * (1 - 2*L);
            
                if needleX > (1 - L)
                    needleX = needleX - L;
                end
            
                if needleX < L
                    needleX = needleX + L;
                end
            
                % Get starting Y value for Needle
                needleY = rand(1) * (1 - 2*L);
            
                if needleY > (1 - L)
                    needleY = needleY - L;
                end
            
                if needleY < L
                    needleY = needleY + L;
                end
            
                % Get Angle for needle
                needleAng = rand(1) * 360;
            
                % Get ending X value for Needle
                needleX2 = needleX  + (L * cosd(needleAng));
            
                % Get ending Y value for Needle
                needleY2 = needleY + (L * sind(needleAng));
                
                % Reset condtion
                check = 0;
                
                % Check if needle crosses vertical lines
                for x = L:(2 * L):1 - L
                    if (needleX < x) && (needleX2 > x)
                        check = 1;
                        if num < 1000
                            plot(ax3, [needleX; needleX2], [needleY; needleY2], 'g');
                        end
                    end
                    
                    if (needleX > x) && (needleX2 < x)
                        check = 1;
                        if num < 1000
                            plot(ax3, [needleX; needleX2], [needleY; needleY2], 'g');
                        end
                    end
                end
                
                if check == 0
                    % Check if needle crosses horizontal lines
                    for y = L:(2 * L):1 - L
                        if (needleY < y) && (needleY2 > y)
                            check = 1;
                            if num < 1000
                                plot(ax3, [needleX; needleX2], [needleY; needleY2], 'g');
                            end
                        end
            
                        if (needleY > y) && (needleY2 < y)
                            check = 1;
                            if num < 1000
                                plot(ax3, [needleX; needleX2], [needleY; needleY2], 'g');
                            end
                        end
                    end
                end
                
                if check == 1
                    count = count + 1;
                end
            
                % Plot needles
                if check == 0 && num < 1000
                    hold on
                    plot(ax3, [needleX; needleX2], [needleY; needleY2], 'k');
                end
            end
            
            top = ((2 * L) * ((2 * L) + (2 * L))) - (L * L);
            
            bottom = (2 * L) * (2 * L) * (count / N);
            
            pie = top / bottom;
            
            ax3.Title.String = string(pie);
            axis square
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Task1Button = uibutton(app.UIFigure, 'push');
            app.Task1Button.ButtonPushedFcn = createCallbackFcn(app, @Task1ButtonPushed, true);
            app.Task1Button.Text = 'Task 1';
            app.NumberofSquaresSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofSquaresSpinnerLabel.Visible = 'off';
            app.NumberofSquaresSpinnerLabel.Text = 'Number of Squares';
            app.NumberofSquaresSpinner = uispinner(app.UIFigure);
            app.NumberofSquaresSpinner.Step = 50;
            app.NumberofSquaresSpinner.Limits = [0 1000000];
            app.NumberofSquaresSpinner.Visible = 'off';
            app.NumberofSquaresSpinner.Value = 150;
            app.LengthofSquaresSpinnerLabel = uilabel(app.UIFigure);
            app.LengthofSquaresSpinnerLabel.Visible = 'off';
            app.LengthofSquaresSpinnerLabel.Text = 'Length of Squares';
            app.LengthofSquaresSpinner = uispinner(app.UIFigure);
            app.LengthofSquaresSpinner.Step = 0.1;
            app.LengthofSquaresSpinner.Limits = [0 1];
            app.LengthofSquaresSpinner.Visible = 'off';
            app.LengthofSquaresSpinner.Value = 0.1;
            app.WidthofPlankSpinnerLabel = uilabel(app.UIFigure);
            app.WidthofPlankSpinnerLabel.Visible = 'off';
            app.WidthofPlankSpinnerLabel.Text = 'Width of Plank';
            app.WidthofPlankSpinner = uispinner(app.UIFigure);
            app.WidthofPlankSpinner.Step = 0.1;
            app.WidthofPlankSpinner.Limits = [0 1];
            app.WidthofPlankSpinner.Visible = 'off';
            app.WidthofPlankSpinner.Value = 0.2;
            app.ShowTask1Button = uibutton(app.UIFigure, 'push');
            app.ShowTask1Button.ButtonPushedFcn = createCallbackFcn(app, @ShowTask1ButtonPushed, true);
            app.ShowTask1Button.Text = 'Show Task 1';
            app.ShowTask2Button = uibutton(app.UIFigure, 'push');
            app.ShowTask2Button.ButtonPushedFcn = createCallbackFcn(app, @ShowTask2ButtonPushed, true);
            app.ShowTask2Button.Text = 'Show Task 2';
            app.Task2Button = uibutton(app.UIFigure, 'push');
            app.Task2Button.ButtonPushedFcn = createCallbackFcn(app, @Task2ButtonPushed, true);
            app.Task2Button.Text = 'Task 2';
            app.NumberofSquares2SpinnerLabel = uilabel(app.UIFigure);
            app.NumberofSquares2SpinnerLabel.Visible = 'off';
            app.NumberofSquares2SpinnerLabel.Text = 'Number of Squares 2';
            app.NumberofSquares2Spinner = uispinner(app.UIFigure);
            app.NumberofSquares2Spinner.Limits = [0 100000];
            app.NumberofSquares2Spinner.Visible = 'off';
            app.NumberofSquares2Spinner.Value = 150;
            app.LengthofSquares2SpinnerLabel = uilabel(app.UIFigure);
            app.LengthofSquares2SpinnerLabel.Visible = 'off';
            app.LengthofSquares2SpinnerLabel.Text = 'Length of Squares 2';
            app.LengthofSquares2Spinner = uispinner(app.UIFigure);
            app.LengthofSquares2Spinner.Step = 0.1;
            app.LengthofSquares2Spinner.Limits = [0 1];
            app.LengthofSquares2Spinner.Visible = 'off';
            app.LengthofSquares2Spinner.Value = 0.1;
            app.WidthofPlank2SpinnerLabel = uilabel(app.UIFigure);
            app.WidthofPlank2SpinnerLabel.Visible = 'off';
            app.WidthofPlank2SpinnerLabel.Text = 'Width of Plank 2';
            app.WidthofPlank2Spinner = uispinner(app.UIFigure);
            app.WidthofPlank2Spinner.Step = 0.1;
            app.WidthofPlank2Spinner.Limits = [0 1];
            app.WidthofPlank2Spinner.Visible = 'off';
            app.WidthofPlank2Spinner.Value = 0.1;
            app.ShowTask3Button = uibutton(app.UIFigure, 'push');
            app.ShowTask3Button.Text = 'Show Task 3';
            app.Task3Button = uibutton(app.UIFigure, 'push');
            app.Task3Button.ButtonPushedFcn = createCallbackFcn(app, @Task3ButtonPushed, true);
            app.Task3Button.Text = 'Task 3';
            app.NumberofNeedlesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofNeedlesSpinnerLabel.Text = 'Number of Needles';
            app.NumberofNeedlesSpinner = uispinner(app.UIFigure);
            app.NumberofNeedlesSpinner.Limits = [0 1000000];
            app.NumberofNeedlesSpinner.Value = 150;
            app.LengthofNeedlesSpinnerLabel = uilabel(app.UIFigure);
            app.LengthofNeedlesSpinnerLabel.Text = 'Length of Needles';
            app.LengthofNeedlesSpinner = uispinner(app.UIFigure);
            app.LengthofNeedlesSpinner.Step = 0.1;
            app.LengthofNeedlesSpinner.Limits = [0 1];
            app.LengthofNeedlesSpinner.Value = 0.1;
            app.WidthofPlanksSpinnerLabel = uilabel(app.UIFigure);
            app.WidthofPlanksSpinnerLabel.Text = 'Width of Planks';
            app.WidthofPlanksSpinner = uispinner(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Task 1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Tag = 'task1_axes';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Task 2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Coursework

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end