classdef Coursework2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Task1Tab                        matlab.ui.container.Tab
        RunButton                       matlab.ui.control.Button
        NumberofSquaresSpinnerLabel     matlab.ui.control.Label
        NumberofSquaresSpinner          matlab.ui.control.Spinner
        LengthofSquaresSpinnerLabel     matlab.ui.control.Label
        LengthofSquaresSpinner          matlab.ui.control.Spinner
        LengthofPlanksSpinnerLabel      matlab.ui.control.Label
        LengthofPlanksSpinner           matlab.ui.control.Spinner
        ResetButton                     matlab.ui.control.Button
        ChoosecolourofintersectingsquaresLabel  matlab.ui.control.Label
        REditField_4Label               matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        GEditField_4Label               matlab.ui.control.Label
        GEditField                      matlab.ui.control.NumericEditField
        BEditField_4Label               matlab.ui.control.Label
        BEditField                      matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        Task2Tab                        matlab.ui.container.Tab
        RunButton_2                     matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        NumberofSquaresSpinner_2Label   matlab.ui.control.Label
        NumberofSquaresSpinner_2        matlab.ui.control.Spinner
        LengthofPlanksSpinner_2Label    matlab.ui.control.Label
        LengthofPlanksSpinner_2         matlab.ui.control.Spinner
        LengthofSquaresSpinner_2Label   matlab.ui.control.Label
        LengthofSquaresSpinner_2        matlab.ui.control.Spinner
        ChoosecolourforintersectingsquaresLabel  matlab.ui.control.Label
        REditField_2Label               matlab.ui.control.Label
        REditField_2                    matlab.ui.control.NumericEditField
        BEditField_2Label               matlab.ui.control.Label
        BEditField_2                    matlab.ui.control.NumericEditField
        GEditField_2Label               matlab.ui.control.Label
        GEditField_2                    matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        Task3Tab                        matlab.ui.container.Tab
        RunButton_3                     matlab.ui.control.Button
        ResetButton_3                   matlab.ui.control.Button
        NumberofNeedlesSpinnerLabel     matlab.ui.control.Label
        NumberofNeedlesSpinner          matlab.ui.control.Spinner
        LengthofNeedlesSpinnerLabel     matlab.ui.control.Label
        LengthofNeedlesSpinner          matlab.ui.control.Spinner
        LengthofPlanksSpinner_3Label    matlab.ui.control.Label
        LengthofPlanksSpinner_3         matlab.ui.control.Spinner
        REditFieldLabel                 matlab.ui.control.Label
        REditField_3                    matlab.ui.control.NumericEditField
        GEditFieldLabel                 matlab.ui.control.Label
        GEditField_3                    matlab.ui.control.NumericEditField
        BEditFieldLabel                 matlab.ui.control.Label
        BEditField_3                    matlab.ui.control.NumericEditField
        ChoosecolorofintersectedneedlesLabel  matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        Task4Tab                        matlab.ui.container.Tab
        RunButton_5                     matlab.ui.control.Button
        ResetButton_5                   matlab.ui.control.Button
        NumberofNeedlesSpinner_3Label   matlab.ui.control.Label
        NumberofNeedlesSpinner_3        matlab.ui.control.Spinner
        LengthofPlanksSpinner_5Label    matlab.ui.control.Label
        LengthofPlanksSpinner_5         matlab.ui.control.Spinner
        LengthofNeedlesSpinner_2Label   matlab.ui.control.Label
        LengthofNeedlesSpinner_2        matlab.ui.control.Spinner
        REditField_4Label_2             matlab.ui.control.Label
        REditField_4                    matlab.ui.control.NumericEditField
        BEditField_4Label_2             matlab.ui.control.Label
        BEditField_4                    matlab.ui.control.NumericEditField
        GEditField_4Label_2             matlab.ui.control.Label
        GEditField_4                    matlab.ui.control.NumericEditField
        SelectnumberofNeedleswithsimilarorientationSpinnerLabel  matlab.ui.control.Label
        SelectnumberofNeedleswithsimilarorientationSpinner  matlab.ui.control.Spinner
        ColorofintersectingneedlesLabel  matlab.ui.control.Label
        UIAxes5                         matlab.ui.control.UIAxes
        FirstNovelFeautureTab           matlab.ui.container.Tab
        AllowamixtureofdifferentsizedneedlestobeusedLabel  matlab.ui.control.Label
        RunButton_4                     matlab.ui.control.Button
        ResetButton_4                   matlab.ui.control.Button
        MinLengthofNeedlesSpinnerLabel  matlab.ui.control.Label
        MinLengthofNeedlesSpinner       matlab.ui.control.Spinner
        MaxLengthofNeedlesSpinnerLabel  matlab.ui.control.Label
        MaxLengthofNeedlesSpinner       matlab.ui.control.Spinner
        LengthofPlanksSpinner_4Label    matlab.ui.control.Label
        LengthofPlanksSpinner_4         matlab.ui.control.Spinner
        NumberofNeedlesSpinner_2Label   matlab.ui.control.Label
        NumberofNeedlesSpinner_2        matlab.ui.control.Spinner
        UIAxes4                         matlab.ui.control.UIAxes
        SecondNovelFeatureTab           matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        RunButton_6                     matlab.ui.control.Button
        ResetButton_6                   matlab.ui.control.Button
        NumberofPentagonsSliderLabel    matlab.ui.control.Label
        NumberofPentagonsSlider         matlab.ui.control.Slider
        LengthofPentagonsSliderLabel    matlab.ui.control.Label
        LengthofPentagonsSlider         matlab.ui.control.Slider
        LengthofPlanksSliderLabel       matlab.ui.control.Label
        LengthofPlanksSlider            matlab.ui.control.Slider
        Label_2                         matlab.ui.control.Label
        REditField_5Label               matlab.ui.control.Label
        REditField_5                    matlab.ui.control.NumericEditField
        BEditField_5Label               matlab.ui.control.Label
        BEditField_5                    matlab.ui.control.NumericEditField
        GEditField_5Label               matlab.ui.control.Label
        GEditField_5                    matlab.ui.control.NumericEditField
        UIAxes6                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Number of squares
            N = app.NumberofSquaresSpinner.Value;
            
            % Length of needle
            L = app.LengthofSquaresSpinner.Value;
            
            % Length of planks
            D = app.LengthofPlanksSpinner.Value;
            
            % Drawing lines
            y = L:D:1 - L;
            
            % Colour of intersected needles
            R = app.REditField.Value;
            G = app.GEditField.Value;
            B = app.BEditField.Value;
            
            colour = [R G B];
            
            % Plotting floorboards
            for x = L:D:(1 - L)
                 plot(app.UIAxes, x*ones(size(y)),y, 'k');
            end
            
            % Used to calculate PI
            count = 0;
            
            for num = 1:N
                needleX = rand(1) * (1 - 2*L);
            
                 if needleX > (1 - L)
                     needleX = needleX - L;
                 end
             
                 if needleX < L
                     needleX = needleX + L;
                 end
            
                % Get starting Y value for Needle
                needleY = rand(1) * (1 - 2*L);
            
                 if needleY > (1 - L)
                     needleY = needleY - L;
                 end
             
                 if needleY < L
                     needleY = needleY + L;
                 end
            
                % Get Angle for needle
                needleAng = rand(1) * 360;
            
                % Get ending X value for Needle
                needleX2 = needleX  + (L * cosd(needleAng));
            
                % Get ending Y value for Needle
                needleY2 = needleY + (L * sind(needleAng));
            
            
                % Get start of needle perpendicular to needleX
                needleX3 = needleX + (L * cosd(needleAng + 90));
            
                % Get start of needle perpendicular to needleY
                needleY3 = needleY + (L * sind(needleAng + 90));
            
                % Get start of needle perpendicular to needleX2
                needleX4 = needleX2 + (L * cosd(needleAng + 90));
            
                % Get start of needle perpendicular to needleY2
                needleY4 = needleY2 + (L * sind(needleAng + 90));
            
                % Reset condtions
                check = 0;
                
                for x = L:D:(1 - L)  
                    
                    
                    % Check if first needle crosses a line
                    if ((needleX < x) && (needleX2 > x)) || ((needleX > x) && (needleX2 < x))
                        check = 1;
                        if num < 150
                            plot(app.UIAxes, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                            plot(app.UIAxes, [needleX; needleX3], [needleY; needleY3], 'color', colour);
                            plot(app.UIAxes, [needleX2; needleX4], [needleY2; needleY4], 'color', colour);
                            plot(app.UIAxes, [needleX3; needleX4], [needleY3; needleY4], 'color', colour); 
                        end
                        count = count + 1;
                    end
                    
                    
                    % Check if second needle crosses a line
                    if ((needleX < x) && (needleX3 > x)) || ((needleX > x) && (needleX3 < x))
                        check = 1;
                        if num < 150 && check == 0
                             plot(app.UIAxes, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                             plot(app.UIAxes, [needleX; needleX3], [needleY; needleY3], 'color', colour);
                             plot(app.UIAxes, [needleX2; needleX4], [needleY2; needleY4], 'color', colour);
                             plot(app.UIAxes, [needleX3; needleX4], [needleY3; needleY4], 'color', colour); 
                        end
                        count = count + 1;
                    end
                    
                    
                    % Check if third needle crosses a line
                    if ((needleX2 < x) && (needleX4 > x)) || ((needleX2 > x) && (needleX4 < x))
                        check = 1;
                        if num < 150 && check == 0
                            plot(app.UIAxes, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                            plot(app.UIAxes, [needleX; needleX3], [needleY; needleY3], 'color', colour);
                            plot(app.UIAxes, [needleX2; needleX4], [needleY2; needleY4], 'color', colour);
                            plot(app.UIAxes, [needleX3; needleX4], [needleY3; needleY4], 'color', colour); 
                        end
                        count = count + 1;
                    end 
                    
                    
                    % Check if fourth needle crosses a line
                    if ((needleX3 < x) && (needleX4 > x)) || ((needleX3 > x) && (needleX4 < x))
                        check = 1;
                        if num < 150 && check == 0
                            plot(app.UIAxes, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                            plot(app.UIAxes, [needleX; needleX3], [needleY; needleY3], 'color', colour);
                            plot(app.UIAxes, [needleX2; needleX4], [needleY2; needleY4], 'color', colour);
                            plot(app.UIAxes, [needleX3; needleX4], [needleY3; needleY4], 'color', colour); 
                        end
                        count = count + 1;
                    end 
                end
                
                
                if check == 0 && num < 150
                    plot(app.UIAxes, [needleX; needleX2], [needleY; needleY2], 'k');
                    plot(app.UIAxes, [needleX; needleX3], [needleY; needleY3], 'k');
                    plot(app.UIAxes, [needleX2; needleX4], [needleY2; needleY4], 'k');
                    plot(app.UIAxes, [needleX3; needleX4], [needleY3; needleY4], 'k'); 
                end
                
            end
            
            pie = (N * 4) / count;
            
            app.UIAxes.Title.String = string(pie);
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            % Number of squares
            N = app.NumberofSquaresSpinner_2.Value;
            
            % Length of needle
            L = app.LengthofSquaresSpinner_2.Value;
            
            % Length of planks
            D = app.LengthofPlanksSpinner_2.Value;
            
            % Drawing lines
            y = L:D:1 - L;
            
            % colour of intersecting squares
            R = app.REditField_2.Value;
            G = app.GEditField_2.Value;
            B = app.BEditField_2.Value;
            
            colour = [R G B];
            
            % Plotting floorboards
            for x = L:D:(1 - L)
                 plot(app.UIAxes2, x*ones(size(y)),y, 'k');
            end
            
            % Counters
            Nc = 0;
            Ncc = 0;
            
            for num = 1:N
                needleX = rand(1) * (1 - 2*L);
            
                 if needleX > (1 - L)
                     needleX = needleX - L;
                 end
             
                 if needleX < L
                     needleX = needleX + L;
                 end
            
                % Get starting Y value for Needle
                needleY = rand(1) * (1 - 2*L);
            
                 if needleY > (1 - L)
                     needleY = needleY - L;
                 end
             
                 if needleY < L
                     needleY = needleY + L;
                 end
            
                % Get Angle for needle
                needleAng = rand(1) * 360;
            
                % Get ending X value for Needle
                needleX2 = needleX  + (L * cosd(needleAng));
            
                % Get ending Y value for Needle
                needleY2 = needleY + (L * sind(needleAng));
            
            
                % Get start of needle perpendicular to needleX
                needleX3 = needleX + (L * cosd(needleAng + 90));
            
                % Get start of needle perpendicular to needleY
                needleY3 = needleY + (L * sind(needleAng + 90));
            
                % Get start of needle perpendicular to needleX2
                needleX4 = needleX2 + (L * cosd(needleAng + 90));
            
                % Get start of needle perpendicular to needleY2
                needleY4 = needleY2 + (L * sind(needleAng + 90));
            
                % Reset condtions
                check1 = 0;
                check2 = 0;
                check3 = 0;
                check4 = 0;
                
                for x = L:D:(1 - L)  
                    
                    
                    % Check if first needle crosses a line
                    if ((needleX < x) && (needleX2 > x)) || ((needleX > x) && (needleX2 < x))
                        check1 = 1;
                        if num < 150
                            plot(app.UIAxes2, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                            plot(app.UIAxes2, [needleX; needleX3], [needleY; needleY3], 'color', colour);
                            plot(app.UIAxes2, [needleX2; needleX4], [needleY2; needleY4], 'color', colour);
                            plot(app.UIAxes2, [needleX3; needleX4], [needleY3; needleY4],  'color', colour);
                        end
                    end
                    
                    
                    % Check if second needle crosses a line
                    if ((needleX < x) && (needleX3 > x)) || ((needleX > x) && (needleX3 < x))
                        check2 = 1;
                        if num < 150
                             plot(app.UIAxes2, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                             plot(app.UIAxes2, [needleX; needleX3], [needleY; needleY3], 'color', colour);
                             plot(app.UIAxes2, [needleX2; needleX4], [needleY2; needleY4], 'color', colour);
                             plot(app.UIAxes2, [needleX3; needleX4], [needleY3; needleY4], 'color', colour); 
                        end
                    end
                    
                    
                    % Check if third needle crosses a line
                    if ((needleX2 < x) && (needleX4 > x)) || ((needleX2 > x) && (needleX4 < x))
                        check3 = 1;
                        if num < 150
                            plot(app.UIAxes2, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                            plot(app.UIAxes2, [needleX; needleX3], [needleY; needleY3], 'color', colour);
                            plot(app.UIAxes2, [needleX2; needleX4], [needleY2; needleY4], 'color', colour);
                            plot(app.UIAxes2, [needleX3; needleX4], [needleY3; needleY4], 'color', colour); 
                        end
                    end 
                    
                    
                    % Check if fourth needle crosses a line
                    if ((needleX3 < x) && (needleX4 > x)) || ((needleX3 > x) && (needleX4 < x))
                        check4 = 1;
                        if num < 150
                            plot(app.UIAxes2, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                            plot(app.UIAxes2, [needleX; needleX3], [needleY; needleY3], 'color', colour);
                            plot(app.UIAxes2, [needleX2; needleX4], [needleY2; needleY4], 'color', colour);
                            plot(app.UIAxes2, [needleX3; needleX4], [needleY3; needleY4], 'color', colour); 
                        end
                    end
                end
                
                % Check if line intersects two consecutive sides
                if check1 == 1 && check2 == 1
                    Ncc = Ncc + 1;
                end
                
                if check1 == 1 && check3 == 1
                    Ncc = Ncc + 1;
                end
                
                if check3 == 1 && check4 == 1
                    Ncc = Ncc + 1;
                end
                
                if check2 == 1 && check4 == 1
                    Ncc = Ncc + 1;
                end
                
                if check1 == 0 && check2 == 0 && check3 == 0 && check4 == 0
                    if num < 150
                        plot(app.UIAxes2, [needleX; needleX2], [needleY; needleY2], 'k');
                        plot(app.UIAxes2, [needleX; needleX3], [needleY; needleY3], 'k');
                        plot(app.UIAxes2, [needleX2; needleX4], [needleY2; needleY4], 'k');
                        plot(app.UIAxes2, [needleX3; needleX4], [needleY3; needleY4], 'k');
                    end
                else
                    Nc = Nc + 1;
                    if num < 150
                        plot(app.UIAxes2, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                        plot(app.UIAxes2, [needleX; needleX3], [needleY; needleY3], 'color', colour);
                        plot(app.UIAxes2, [needleX2; needleX4], [needleY2; needleY4], 'color', colour);
                        plot(app.UIAxes2, [needleX3; needleX4], [needleY3; needleY4], 'color', colour);
                    end
                end
                
            end
            
            root2 = 2 - (Ncc / Nc);
            
            app.UIAxes2.Title.String = string(root2);
        end

        % Button pushed function: RunButton_3
        function RunButton_3Pushed(app, event)
            % Number of needles
            N = app.NumberofNeedlesSpinner.Value;
            
            % Length of needles
            L = app.LengthofNeedlesSpinner.Value;
            
            % Length of planks
            D = app.LengthofPlanksSpinner_3.Value;
            
            % Color of intersecting needles
            R = app.REditField_3.Value;
            G = app.GEditField_3.Value;
            B = app.BEditField_3.Value;
            
            colour = [R G B];
            
            % Drawing vertical lines
            y = L:D:1 - L;
            
            % Drawing horizontal lines
            x = L:D:1 - L;
            
            % Plotting vertical lines
            for xVal = L:D:1 - L
                 plot(app.UIAxes3, xVal*ones(size(y)),y, 'k');
            end
            
            % Plotting vertical lines
            for yVal = L:D:1 - L
                 plot(app.UIAxes3, x, yVal*ones(size(x)), 'k');
            end
            
            % Used to calculate PI
            count = 0;
            
            
            for num = 1:N
                % Get starting X value for Needle
                needleX = rand(1) * (1 - 2*L);
            
                if needleX > (1 - L)
                    needleX = needleX - L;
                end
            
                if needleX < L
                    needleX = needleX + L;
                end
            
                % Get starting Y value for Needle
                needleY = rand(1) * (1 - 2*L);
            
                if needleY > (1 - L)
                    needleY = needleY - L;
                end
            
                if needleY < L
                    needleY = needleY + L;
                end
            
                % Get Angle for needle
                needleAng = rand(1) * 360;
            
                % Get ending X value for Needle
                needleX2 = needleX  + (L * cosd(needleAng));
            
                % Get ending Y value for Needle
                needleY2 = needleY + (L * sind(needleAng));
                
                % Reset condtion
                check = 0;
                
                % Check if needle crosses vertical lines
                for x = L:D:1 - L
                    if (needleX < x) && (needleX2 > x)
                        check = 1;
                        if num < 150
                            plot(app.UIAxes3, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                        end
                    end
                    
                    if (needleX > x) && (needleX2 < x)
                        check = 1;
                        if num < 150
                            plot(app.UIAxes3, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                        end
                    end
                end
                
                if check == 0
                    % Check if needle crosses horizontal lines
                    for y = L:D:1 - L
                        if (needleY < y) && (needleY2 > y)
                            check = 1;
                            if num < 150
                                plot(app.UIAxes3, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                            end
                        end
            
                        if (needleY > y) && (needleY2 < y)
                            check = 1;
                            if num < 150
                                plot(app.UIAxes3, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                            end
                        end
                    end
                end
                
                if check == 1
                    count = count + 1;
                end
            
                % Plot needles
                if check == 0 && num < 150
                    plot(app.UIAxes3, [needleX; needleX2], [needleY; needleY2], 'k');
                end
            end
            
            top = ((2 * L) * (D + D)) - (L * L);
            
            bottom = D * D * (count / N);
            
            pie = top / bottom;
            
            app.UIAxes3.Title.String = string(pie);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            
            app.UIAxes.Title.String = 'Task 1';
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            cla(app.UIAxes2);
            
            app.UIAxes2.Title.String = 'Task 2';
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            cla(app.UIAxes3);
            
            app.UIAxes3.Title.String = 'Task 3';
        end

        % Button pushed function: RunButton_4
        function RunButton_4Pushed(app, event)
            % Number of needles
            N = app.NumberofNeedlesSpinner_2.Value;
            
            % Length of needles
            minL = app.MinLengthofNeedlesSpinner.Value;
            maxL = app.MaxLengthofNeedlesSpinner.Value;
                
            % Length of planks
            D = app.LengthofPlanksSpinner_4.Value;
            
            if minL > maxL
                temp = maxL;
                maxL = minL;
                minL = temp;
            end
            
            L = (maxL + minL) / 2;
            
            % Drawing lines
            y = L:D:1 - L;
            
            % Plotting floorboards
            for x = L:D:1 - L
                 plot(app.UIAxes4, x*ones(size(y)),y, 'k');
            end
            
            % Used to calculate PI
            count = 0;


            for num = 1:N
                % Random number between minL and maxL
                randL = (maxL - minL).*rand(1) + minL;
                
                % Get starting X value for Needle
                needleX = rand(1) * (1 - 2*randL);
            
                if needleX > (1 - L)
                    needleX = needleX - L;
                end
            
                if needleX < L
                    needleX = needleX + L;
                end
            
                % Get starting Y value for Needle
                needleY = rand(1) * (1 - 2*randL);
            
                if needleY > (1 - L)
                    needleY = needleY - L;
                end
            
                if needleY < L
                    needleY = needleY + L;
                end
            
                % Get Angle for needle
                needleAng = rand(1) * 360;
            
                plot(app.UIAxes4, needleX, needleY)
            
                % Get ending X value for Needle
                needleX2 = needleX  + (randL * cosd(needleAng));
            
                % Get ending Y value for Needle
                needleY2 = needleY + (randL * sind(needleAng));
                
                % Reset condtion
                check = 0;
                
                for x = L:D:1 - L
                    if (needleX < x) && (needleX2 > x)
                        check = 1;
                        if num < 150
                            plot(app.UIAxes4, [needleX; needleX2], [needleY; needleY2], 'g');
                        end
                        count = count + 1;
                    end
                    
                    if (needleX > x) && (needleX2 < x)
                        check = 1;
                        if num < 150
                            plot(app.UIAxes4, [needleX; needleX2], [needleY; needleY2], 'g');
                        end
                        count = count + 1;
                    end
                end
            
                % Plot needles
                if check == 0 && num < 150
                    plot(app.UIAxes4, [needleX; needleX2], [needleY; needleY2], 'k');
                end
            end
            
            pie = N / count;
            
            app.UIAxes4.Title.String = string(pie);
        end

        % Button pushed function: ResetButton_4
        function ResetButton_4Pushed(app, event)
            cla(app.UIAxes4);
            
            app.UIAxes4.Title.String = 'Task 5';
        end

        % Button pushed function: RunButton_5
        function RunButton_5Pushed(app, event)
            % function for interacting with needles
            function mouseClick(src,~)
                % src refers to the needle clicked                 
                src.Color = 'r';
                srcXValues = src.XData;
                srcYValues = src.YData;
                
                srcX1 = srcXValues(2) - srcXValues(1);
                srcY1 = srcYValues(2) - srcYValues(1);
                
                srcAngle1 = atan(srcY1 / srcX1) * 180/pi;
                
                m = 0;
                
                if (srcY1 / srcX1) > 0
                    m = 1;
                end
                
                selected = gobjects(1, sim);
                
                min = 180; 
                mark = src;
                
                for numm = 1:sim
                    for number = 1:100
                        p = needles(number);
                        if ismember(p, selected)
                            continue
                        end
                        
                        xValues = p.XData;
                        yValues = p.YData;
                        
                        pX1 = xValues(2) - xValues(1);
                        pY1 = yValues(2) - yValues(1);
                        
                        angle1 = atan(pY1 / pX1) * 180/pi;
                        
                        if m == 0
                            if (srcAngle1 - angle1) < min && (pY1 / pX1) < 0
                                min = angle1;
                                mark = p;
                            elseif (angle1 + srcAngle1) < min && (pY1 / pX1) < 0
                                min = angle1;
                                mark = p;   
                            end
                        else
                            if (srcAngle1 - angle1) < min && (pY1 / pX1) > 0
                                min = angle1;
                                mark = p;
                            elseif (angle1 + srcAngle1) < min && (pY1 / pX1) > 0
                                min = angle1;
                                mark = p;   
                            end
                        end
                    end
                    selected(numm) = mark;
                    mark.Color = 'b';
                    min = 180;
                end
                
                app.UIAxes5.Subtitle.String = 'Showing ' + string(sim) + ' needle(s) with similar orientation.';
                clear selected;
            end
            
            % Number of needles with similar orientation
            sim = app.SelectnumberofNeedleswithsimilarorientationSpinner.Value;
            
            % Number of needles
            N = app.NumberofNeedlesSpinner_3.Value;
            
            % Length of needles
            L = app.LengthofNeedlesSpinner_2.Value;
            
            % Length of planks
            D = app.LengthofPlanksSpinner_5.Value;
            
            % Color of intersecting needles
            R = app.REditField_4.Value;
            G = app.GEditField_4.Value;
            B = app.BEditField_4.Value;
            
            colour = [R G B];
            
            
            % Create array for needles
            needles = gobjects(1, 100);
            
            % Drawing vertical lines
            y = L:D:1 - L;
            
            % Drawing horizontal lines
            x = L:D:1 - L;
            
            % Plotting vertical lines
            for xVal = L:D:1 - L
                 plot(app.UIAxes5, xVal*ones(size(y)),y, 'k');
            end
            
            % Plotting vertical lines
            for yVal = L:D:1 - L
                 plot(app.UIAxes5, x, yVal*ones(size(x)), 'k');
            end
            
            % Used to calculate PI
            count = 0;
           
            for num = 1:N
                % Get starting X value for Needle
                needleX = rand(1) * (1 - 2*L);
            
                if needleX > (1 - L)
                    needleX = needleX - L;
                end
            
                if needleX < L
                    needleX = needleX + L;
                end
            
                % Get starting Y value for Needle
                needleY = rand(1) * (1 - 2*L);
            
                if needleY > (1 - L)
                    needleY = needleY - L;
                end
            
                if needleY < L
                    needleY = needleY + L;
                end
            
                % Get Angle for needle
                needleAng = rand(1) * 360;
            
                % Get ending X value for Needle
                needleX2 = needleX  + (L * cosd(needleAng));
            
                % Get ending Y value for Needle
                needleY2 = needleY + (L * sind(needleAng));
                
                % Reset condtion
                check = 0;
                
                % Check if needle crosses vertical lines
                for x = L:D:1 - L
                    if (needleX < x) && (needleX2 > x)
                        check = 1;
                        if num < 101
                            p = plot(app.UIAxes5, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                            p.ButtonDownFcn=@mouseClick;
                            needles(num) = p;
                        end
                    end
                    
                    if (needleX > x) && (needleX2 < x)
                        check = 1;
                        if num < 101
                            p = plot(app.UIAxes5, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                            p.ButtonDownFcn=@mouseClick;
                            needles(num) = p;
                        end
                    end
                end
                
                if check == 0
                    % Check if needle crosses horizontal lines
                    for y = L:D:1 - L
                        if (needleY < y) && (needleY2 > y)
                            check = 1;
                            if num < 101
                                p = plot(app.UIAxes5, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                                p.ButtonDownFcn=@mouseClick;
                                needles(num) = p;
                            end
                        end
            
                        if (needleY > y) && (needleY2 < y)
                            check = 1;
                            if num < 101
                                p = plot(app.UIAxes5, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                                p.ButtonDownFcn=@mouseClick;
                                needles(num) = p;
                            end
                        end
                    end
                end
                
                if check == 1
                    count = count + 1;
                end
            
                % Plot needles
                if check == 0 && num < 101
                    p = plot(app.UIAxes5, [needleX; needleX2], [needleY; needleY2], 'k');
                    p.ButtonDownFcn=@mouseClick;
                    needles(num) = p;
                end
            end
            
            top = ((2 * L) * (D + D)) - (L * L);
            
            bottom = D * D * (count / N);
            
            pie = top / bottom;
            
            app.UIAxes5.Title.String = string(pie);
        end

        % Button pushed function: ResetButton_5
        function ResetButton_5Pushed(app, event)
            cla(app.UIAxes5);
            
            app.UIAxes5.Title.String = 'Task 4';
            app.UIAxes5.Subtitle.String = '';
        end

        % Button pushed function: RunButton_6
        function RunButton_6Pushed(app, event)
            % Number of pentagons
            N = app.NumberofPentagonsSlider.Value;
            
            % Length of pentagons
            L = app.LengthofPentagonsSlider.Value;
            
            % Length of Planks
            D = app.LengthofPlanksSpinner.Value;
            
            % Color of intersecting pentagon
            R = app.REditField_5.Value;
            G = app.GEditField_5.Value;
            B = app.BEditField_5.Value;
            
            colour = [R G B];
            
            % Drawing lines
            y = L:D:1 - L;
            
            % Plotting floorboards
            for x = L:D:1 - L
                 plot(app.UIAxes6, x*ones(size(y)),y, 'k');
            end
            
            % Counters
            Nc = 0;
            Ncc = 0;
            
            for num = 1:N
                % Get starting X value for 1st Needle
                needleX = rand(1) * (1 - 2*L);
            
                if needleX > (1 - L)
                    needleX = needleX - L;
                end
            
                if needleX < L
                    needleX = needleX + L;
                end
            
                % Get starting Y value for 1st Needle
                needleY = rand(1) * (1 - 2*L);
            
                if needleY > (1 - L)
                    needleY = needleY - L;
                end
            
                if needleY < L
                    needleY = needleY + L;
                end
            
                % Get Angle for 1st needle
                needleAng = rand(1) * 360;
            
                % Get ending X value for 1st Needle
                needleX2 = needleX  + (L * cosd(needleAng));
            
                % Get ending Y value for 1st Needle
                needleY2 = needleY + (L * sind(needleAng));
                
                
                % Get start of 2nd needle
                needleX3 = needleX + (L * cosd(needleAng + 108));
                needleY3 = needleY + (L * sind(needleAng + 108));
                
                % Get start of 3rd needle
                needleX4 = needleX2 + (L * cosd(needleAng + 72));
                needleY4 = needleY2 + (L * sind(needleAng + 72));
                
                % Get start of 4th needle
                needleX5 = needleX3 + (L * cosd(needleAng + 36));
                needleY5 = needleY3 + (L * sind(needleAng + 36));
                
                % Reset condtion
                check1 = 0;
                check2 = 0;
                check3 = 0;
                check4 = 0;
                check5 = 0;
                
                for x = L:D:1 - L
                    if ((needleX < x) && (needleX2 > x)) || ((needleX > x) && (needleX2 < x))
                        check1 = 1;
                        if num < 100
                            plot(app.UIAxes6, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                            plot(app.UIAxes6, [needleX; needleX3], [needleY; needleY3], 'color', colour);
                            plot(app.UIAxes6, [needleX2; needleX4], [needleY2; needleY4], 'color', colour);
                            plot(app.UIAxes6, [needleX3; needleX5], [needleY3; needleY5], 'color', colour);
                            plot(app.UIAxes6, [needleX5; needleX4], [needleY5; needleY4], 'color', colour);
                        end
                    end
                    
                    if ((needleX < x) && (needleX3 > x)) || ((needleX > x) && (needleX3 < x))
                        check2 = 1;
                        if num < 100
                            plot(app.UIAxes6, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                            plot(app.UIAxes6, [needleX; needleX3], [needleY; needleY3], 'color', colour);
                            plot(app.UIAxes6, [needleX2; needleX4], [needleY2; needleY4], 'color', colour);
                            plot(app.UIAxes6, [needleX3; needleX5], [needleY3; needleY5], 'color', colour);
                            plot(app.UIAxes6, [needleX5; needleX4], [needleY5; needleY4], 'color', colour);
                        end
                    end
                    
                    if ((needleX2 < x) && (needleX4 > x)) || ((needleX2 > x) && (needleX4 < x))
                        check3 = 1;
                        if num < 100
                            plot(app.UIAxes6, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                            plot(app.UIAxes6, [needleX; needleX3], [needleY; needleY3], 'color', colour);
                            plot(app.UIAxes6, [needleX2; needleX4], [needleY2; needleY4], 'color', colour);
                            plot(app.UIAxes6, [needleX3; needleX5], [needleY3; needleY5], 'color', colour);
                            plot(app.UIAxes6, [needleX5; needleX4], [needleY5; needleY4], 'color', colour);
                        end
                    end
                    
                    if ((needleX3 < x) && (needleX5 > x)) || ((needleX3 > x) && (needleX5 < x))
                        check4 = 1;
                        if num < 100
                            plot(app.UIAxes6, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                            plot(app.UIAxes6, [needleX; needleX3], [needleY; needleY3], 'color', colour);
                            plot(app.UIAxes6, [needleX2; needleX4], [needleY2; needleY4], 'color', colour);
                            plot(app.UIAxes6, [needleX3; needleX5], [needleY3; needleY5], 'color', colour);
                            plot(app.UIAxes6, [needleX5; needleX4], [needleY5; needleY4], 'color', colour);
                        end
                    end
                    
                    if ((needleX5 < x) && (needleX4 > x)) || ((needleX5 > x) && (needleX4 < x))
                        check5 = 1;
                        if num < 100
                            plot(app.UIAxes6, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                            plot(app.UIAxes6, [needleX; needleX3], [needleY; needleY3], 'color', colour);
                            plot(app.UIAxes6, [needleX2; needleX4], [needleY2; needleY4], 'color', colour);
                            plot(app.UIAxes6, [needleX3; needleX5], [needleY3; needleY5], 'color', colour);
                            plot(app.UIAxes6, [needleX5; needleX4], [needleY5; needleY4], 'color', colour);
                        end
                    end
                end
            
                % Check if line intersects two consecutive sides
                if check1 == 1 && check2 == 1
                    Ncc = Ncc + 1;
                end
            
                if check1 == 1 && check3 == 1
                    Ncc = Ncc + 1;
                end
            
                if check3 == 1 && check5 == 1
                    Ncc = Ncc + 1;
                end
            
                if check2 == 1 && check4 == 1
                    Ncc = Ncc + 1;
                end
                
                if check5 == 1 && check4 == 1
                    Ncc = Ncc + 1;
                end
                
                % Plot needles
                if check1 == 0 && check2 == 0 && check3 == 0 && check4 == 0 && check5 == 0
                    if num < 100
                        plot(app.UIAxes6, [needleX; needleX2], [needleY; needleY2], 'k');
                        plot(app.UIAxes6, [needleX; needleX3], [needleY; needleY3], 'k');
                        plot(app.UIAxes6, [needleX2; needleX4], [needleY2; needleY4], 'k');
                        plot(app.UIAxes6, [needleX3; needleX5], [needleY3; needleY5], 'k');
                        plot(app.UIAxes6, [needleX5; needleX4], [needleY5; needleY4], 'k');
                    end
                else
                    Nc = Nc + 1;
                    if num < 100
                        plot(app.UIAxes6, [needleX; needleX2], [needleY; needleY2], 'color', colour);
                        plot(app.UIAxes6, [needleX; needleX3], [needleY; needleY3], 'color', colour);
                        plot(app.UIAxes6, [needleX2; needleX4], [needleY2; needleY4], 'color', colour);
                        plot(app.UIAxes6, [needleX3; needleX5], [needleY3; needleY5], 'color', colour);
                        plot(app.UIAxes6, [needleX5; needleX4], [needleY5; needleY4], 'color', colour);
                    end
                end
                    
            end
            
            gold = 2 - (Ncc / Nc);
            
            app.UIAxes6.Title.String = string(gold);
        end

        % Button pushed function: ResetButton_6
        function ResetButton_6Pushed(app, event)
            cla(app.UIAxes6);
            
            app.UIAxes6.Title.String = 'Task 5';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Task1Tab = uitab(app.TabGroup);
            app.Task1Tab.Title = 'Task 1';
            app.RunButton = uibutton(app.Task1Tab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NumberofSquaresSpinnerLabel = uilabel(app.Task1Tab);
            app.NumberofSquaresSpinnerLabel.Text = 'Number of Squares';
            app.NumberofSquaresSpinner = uispinner(app.Task1Tab);
            app.NumberofSquaresSpinner.Limits = [0 1000000];
            app.NumberofSquaresSpinner.Value = 150;
            app.LengthofSquaresSpinnerLabel = uilabel(app.Task1Tab);
            app.LengthofSquaresSpinnerLabel.Text = 'Length of Squares';
            app.LengthofSquaresSpinner = uispinner(app.Task1Tab);
            app.LengthofSquaresSpinner.Step = 0.025;
            app.LengthofSquaresSpinner.Limits = [0.025 1];
            app.LengthofSquaresSpinner.Value = 0.1;
            app.LengthofPlanksSpinnerLabel = uilabel(app.Task1Tab);
            app.LengthofPlanksSpinnerLabel.Text = 'Length of Planks';
            app.LengthofPlanksSpinner = uispinner(app.Task1Tab);
            app.LengthofPlanksSpinner.Step = 0.05;
            app.LengthofPlanksSpinner.Limits = [0.05 1];
            app.LengthofPlanksSpinner.Value = 0.2;
            app.ResetButton = uibutton(app.Task1Tab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ChoosecolourofintersectingsquaresLabel = uilabel(app.Task1Tab);
            app.ChoosecolourofintersectingsquaresLabel.Text = 'Choose colour of intersecting squares';
            app.REditField_4Label = uilabel(app.Task1Tab);
            app.REditField_4Label.Text = 'R';
            app.REditField = uieditfield(app.Task1Tab, 'numeric');
            app.REditField.Limits = [0 1];
            app.GEditField_4Label = uilabel(app.Task1Tab);
            app.GEditField_4Label.Text = 'G';
            app.GEditField = uieditfield(app.Task1Tab, 'numeric');
            app.GEditField.Limits = [0 1];
            app.GEditField.Value = 1;
            app.BEditField_4Label = uilabel(app.Task1Tab);
            app.BEditField_4Label.Text = 'B';
            app.BEditField = uieditfield(app.Task1Tab, 'numeric');
            app.BEditField.Limits = [0 1];
            app.UIAxes = uiaxes(app.Task1Tab);
            title(app.UIAxes, {'Task 1'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.Task2Tab = uitab(app.TabGroup);
            app.Task2Tab.Title = 'Task 2';
            app.RunButton_2 = uibutton(app.Task2Tab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.ResetButton_2 = uibutton(app.Task2Tab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = {'Reset'; ''};
            app.NumberofSquaresSpinner_2Label = uilabel(app.Task2Tab);
            app.NumberofSquaresSpinner_2Label.Text = 'Number of Squares';
            app.NumberofSquaresSpinner_2 = uispinner(app.Task2Tab);
            app.NumberofSquaresSpinner_2.Limits = [0 1000000];
            app.NumberofSquaresSpinner_2.Value = 150;
            app.LengthofPlanksSpinner_2Label = uilabel(app.Task2Tab);
            app.LengthofPlanksSpinner_2Label.Text = 'Length of Planks';
            app.LengthofPlanksSpinner_2 = uispinner(app.Task2Tab);
            app.LengthofPlanksSpinner_2.Step = 0.05;
            app.LengthofPlanksSpinner_2.Limits = [0.05 1];
            app.LengthofPlanksSpinner_2.Value = 0.2;
            app.LengthofSquaresSpinner_2Label = uilabel(app.Task2Tab);
            app.LengthofSquaresSpinner_2Label.Text = 'Length of Squares';
            app.LengthofSquaresSpinner_2 = uispinner(app.Task2Tab);
            app.LengthofSquaresSpinner_2.Step = 0.025;
            app.LengthofSquaresSpinner_2.Limits = [0.025 1];
            app.LengthofSquaresSpinner_2.Value = 0.1;
            app.ChoosecolourforintersectingsquaresLabel = uilabel(app.Task2Tab);
            app.ChoosecolourforintersectingsquaresLabel.Text = {'Choose colour for intersecting squares'; ''};
            app.REditField_2Label = uilabel(app.Task2Tab);
            app.REditField_2Label.Text = 'R';
            app.REditField_2 = uieditfield(app.Task2Tab, 'numeric');
            app.REditField_2.Limits = [0 1];
            app.BEditField_2Label = uilabel(app.Task2Tab);
            app.BEditField_2Label.Text = 'B';
            app.BEditField_2 = uieditfield(app.Task2Tab, 'numeric');
            app.BEditField_2.Limits = [0 1];
            app.GEditField_2Label = uilabel(app.Task2Tab);
            app.GEditField_2Label.Text = 'G';
            app.GEditField_2 = uieditfield(app.Task2Tab, 'numeric');
            app.GEditField_2.Limits = [0 1];
            app.GEditField_2.Value = 1;
            app.UIAxes2 = uiaxes(app.Task2Tab);
            title(app.UIAxes2, 'Task 2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Task3Tab = uitab(app.TabGroup);
            app.Task3Tab.Title = 'Task 3';
            app.RunButton_3 = uibutton(app.Task3Tab, 'push');
            app.RunButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunButton_3Pushed, true);
            app.RunButton_3.Text = 'Run';
            app.ResetButton_3 = uibutton(app.Task3Tab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.NumberofNeedlesSpinnerLabel = uilabel(app.Task3Tab);
            app.NumberofNeedlesSpinnerLabel.Text = 'Number of Needles';
            app.NumberofNeedlesSpinner = uispinner(app.Task3Tab);
            app.NumberofNeedlesSpinner.Limits = [0 1000000];
            app.NumberofNeedlesSpinner.Value = 150;
            app.LengthofNeedlesSpinnerLabel = uilabel(app.Task3Tab);
            app.LengthofNeedlesSpinnerLabel.Text = 'Length of Needles';
            app.LengthofNeedlesSpinner = uispinner(app.Task3Tab);
            app.LengthofNeedlesSpinner.Step = 0.025;
            app.LengthofNeedlesSpinner.Limits = [0.025 1];
            app.LengthofNeedlesSpinner.Value = 0.1;
            app.LengthofPlanksSpinner_3Label = uilabel(app.Task3Tab);
            app.LengthofPlanksSpinner_3Label.Text = 'Length of Planks';
            app.LengthofPlanksSpinner_3 = uispinner(app.Task3Tab);
            app.LengthofPlanksSpinner_3.Step = 0.05;
            app.LengthofPlanksSpinner_3.Limits = [0.05 1];
            app.LengthofPlanksSpinner_3.Value = 0.2;
            app.REditFieldLabel = uilabel(app.Task3Tab);
            app.REditFieldLabel.Text = 'R';
            app.REditField_3 = uieditfield(app.Task3Tab, 'numeric');
            app.REditField_3.Limits = [0 1];
            app.GEditFieldLabel = uilabel(app.Task3Tab);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField_3 = uieditfield(app.Task3Tab, 'numeric');
            app.GEditField_3.Limits = [0 1];
            app.GEditField_3.Value = 1;
            app.BEditFieldLabel = uilabel(app.Task3Tab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField_3 = uieditfield(app.Task3Tab, 'numeric');
            app.BEditField_3.Limits = [0 1];
            app.ChoosecolorofintersectedneedlesLabel = uilabel(app.Task3Tab);
            app.ChoosecolorofintersectedneedlesLabel.Text = 'Choose color of intersected needles';
            app.UIAxes3 = uiaxes(app.Task3Tab);
            title(app.UIAxes3, {'Task 3'; ''})
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Task4Tab = uitab(app.TabGroup);
            app.Task4Tab.Title = 'Task 4';
            app.RunButton_5 = uibutton(app.Task4Tab, 'push');
            app.RunButton_5.ButtonPushedFcn = createCallbackFcn(app, @RunButton_5Pushed, true);
            app.RunButton_5.Text = 'Run';
            app.ResetButton_5 = uibutton(app.Task4Tab, 'push');
            app.ResetButton_5.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_5Pushed, true);
            app.ResetButton_5.Text = 'Reset';
            app.NumberofNeedlesSpinner_3Label = uilabel(app.Task4Tab);
            app.NumberofNeedlesSpinner_3Label.Text = 'Number of Needles';
            app.NumberofNeedlesSpinner_3 = uispinner(app.Task4Tab);
            app.NumberofNeedlesSpinner_3.Limits = [0 1000000];
            app.NumberofNeedlesSpinner_3.Value = 100;
            app.LengthofPlanksSpinner_5Label = uilabel(app.Task4Tab);
            app.LengthofPlanksSpinner_5Label.Text = 'Length of Planks';
            app.LengthofPlanksSpinner_5 = uispinner(app.Task4Tab);
            app.LengthofPlanksSpinner_5.Step = 0.05;
            app.LengthofPlanksSpinner_5.Limits = [0.05 1];
            app.LengthofPlanksSpinner_5.Value = 0.2;
            app.LengthofNeedlesSpinner_2Label = uilabel(app.Task4Tab);
            app.LengthofNeedlesSpinner_2Label.Text = 'Length of Needles';
            app.LengthofNeedlesSpinner_2 = uispinner(app.Task4Tab);
            app.LengthofNeedlesSpinner_2.Step = 0.025;
            app.LengthofNeedlesSpinner_2.Limits = [0.025 1];
            app.LengthofNeedlesSpinner_2.Value = 0.1;
            app.REditField_4Label_2 = uilabel(app.Task4Tab);
            app.REditField_4Label_2.Text = 'R';
            app.REditField_4 = uieditfield(app.Task4Tab, 'numeric');
            app.REditField_4.Limits = [0 1];
            app.BEditField_4Label_2 = uilabel(app.Task4Tab);
            app.BEditField_4Label_2.Text = 'B';
            app.BEditField_4 = uieditfield(app.Task4Tab, 'numeric');
            app.BEditField_4.Limits = [0 1];
            app.GEditField_4Label_2 = uilabel(app.Task4Tab);
            app.GEditField_4Label_2.Text = 'G';
            app.GEditField_4 = uieditfield(app.Task4Tab, 'numeric');
            app.GEditField_4.Limits = [0 1];
            app.GEditField_4.Value = 1;
            app.SelectnumberofNeedleswithsimilarorientationSpinnerLabel = uilabel(app.Task4Tab);
            app.SelectnumberofNeedleswithsimilarorientationSpinnerLabel.Text = 'Select number of Needles with similar orientation';
            app.SelectnumberofNeedleswithsimilarorientationSpinner = uispinner(app.Task4Tab);
            app.SelectnumberofNeedleswithsimilarorientationSpinner.Limits = [1 20];
            app.SelectnumberofNeedleswithsimilarorientationSpinner.Value = 3;
            app.ColorofintersectingneedlesLabel = uilabel(app.Task4Tab);
            app.ColorofintersectingneedlesLabel.Text = 'Color of intersecting needles';
            app.UIAxes5 = uiaxes(app.Task4Tab);
            title(app.UIAxes5, 'Task 4')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.FirstNovelFeautureTab = uitab(app.TabGroup);
            app.FirstNovelFeautureTab.Title = 'First Novel Feauture';
            app.AllowamixtureofdifferentsizedneedlestobeusedLabel = uilabel(app.FirstNovelFeautureTab);
            app.AllowamixtureofdifferentsizedneedlestobeusedLabel.Text = {'Allow a mixture of different sized needles to be used.'; ''};
            app.RunButton_4 = uibutton(app.FirstNovelFeautureTab, 'push');
            app.RunButton_4.ButtonPushedFcn = createCallbackFcn(app, @RunButton_4Pushed, true);
            app.RunButton_4.Text = 'Run';
            app.ResetButton_4 = uibutton(app.FirstNovelFeautureTab, 'push');
            app.ResetButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_4Pushed, true);
            app.ResetButton_4.Text = 'Reset';
            app.MinLengthofNeedlesSpinnerLabel = uilabel(app.FirstNovelFeautureTab);
            app.MinLengthofNeedlesSpinnerLabel.Text = 'Min Length of Needles';
            app.MinLengthofNeedlesSpinner = uispinner(app.FirstNovelFeautureTab);
            app.MinLengthofNeedlesSpinner.Step = 0.025;
            app.MinLengthofNeedlesSpinner.Limits = [0.025 1];
            app.MinLengthofNeedlesSpinner.Value = 0.025;
            app.MaxLengthofNeedlesSpinnerLabel = uilabel(app.FirstNovelFeautureTab);
            app.MaxLengthofNeedlesSpinnerLabel.Text = 'Max Length  of Needles';
            app.MaxLengthofNeedlesSpinner = uispinner(app.FirstNovelFeautureTab);
            app.MaxLengthofNeedlesSpinner.Step = 0.025;
            app.MaxLengthofNeedlesSpinner.Limits = [0.025 1];
            app.MaxLengthofNeedlesSpinner.Value = 0.175;
            app.LengthofPlanksSpinner_4Label = uilabel(app.FirstNovelFeautureTab);
            app.LengthofPlanksSpinner_4Label.Text = 'Length of Planks';
            app.LengthofPlanksSpinner_4 = uispinner(app.FirstNovelFeautureTab);
            app.LengthofPlanksSpinner_4.Step = 0.05;
            app.LengthofPlanksSpinner_4.Limits = [0.05 1];
            app.LengthofPlanksSpinner_4.Value = 0.2;
            app.NumberofNeedlesSpinner_2Label = uilabel(app.FirstNovelFeautureTab);
            app.NumberofNeedlesSpinner_2Label.Text = 'Number of Needles';
            app.NumberofNeedlesSpinner_2 = uispinner(app.FirstNovelFeautureTab);
            app.NumberofNeedlesSpinner_2.Limits = [0 1000000];
            app.NumberofNeedlesSpinner_2.Value = 100;
            app.UIAxes4 = uiaxes(app.FirstNovelFeautureTab);
            title(app.UIAxes4, 'Task 5')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.SecondNovelFeatureTab = uitab(app.TabGroup);
            app.SecondNovelFeatureTab.Title = 'Second Novel Feature';
            app.Label = uilabel(app.SecondNovelFeatureTab);
            app.Label.Text = '';
            app.RunButton_6 = uibutton(app.SecondNovelFeatureTab, 'push');
            app.RunButton_6.ButtonPushedFcn = createCallbackFcn(app, @RunButton_6Pushed, true);
            app.RunButton_6.Text = 'Run';
            app.ResetButton_6 = uibutton(app.SecondNovelFeatureTab, 'push');
            app.ResetButton_6.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_6Pushed, true);
            app.ResetButton_6.Text = 'Reset';
            app.NumberofPentagonsSliderLabel = uilabel(app.SecondNovelFeatureTab);
            app.NumberofPentagonsSliderLabel.Text = 'Number of Pentagons';
            app.NumberofPentagonsSlider = uislider(app.SecondNovelFeatureTab);
            app.NumberofPentagonsSlider.Limits = [0 1000000];
            app.NumberofPentagonsSlider.Value = 100;
            app.LengthofPentagonsSliderLabel = uilabel(app.SecondNovelFeatureTab);
            app.LengthofPentagonsSliderLabel.Text = 'Length of Pentagons';
            app.LengthofPentagonsSlider = uislider(app.SecondNovelFeatureTab);
            app.LengthofPentagonsSlider.Limits = [0 1];
            app.LengthofPentagonsSlider.Value = 0.1;
            app.LengthofPlanksSliderLabel = uilabel(app.SecondNovelFeatureTab);
            app.LengthofPlanksSliderLabel.Text = 'Length of Planks';
            app.LengthofPlanksSlider = uislider(app.SecondNovelFeatureTab);
            app.LengthofPlanksSlider.Limits = [0.05 1];
            app.LengthofPlanksSlider.Value = 0.2;
            app.Label_2 = uilabel(app.SecondNovelFeatureTab);
            app.Label_2.Text = 'Buffons Needle experment using regular pentagons to estimate the golden ratio';
            app.REditField_5Label = uilabel(app.SecondNovelFeatureTab);
            app.REditField_5Label.Text = 'R';
            app.REditField_5 = uieditfield(app.SecondNovelFeatureTab, 'numeric');
            app.REditField_5.Limits = [0 1];
            app.BEditField_5Label = uilabel(app.SecondNovelFeatureTab);
            app.BEditField_5Label.Text = 'B';
            app.BEditField_5 = uieditfield(app.SecondNovelFeatureTab, 'numeric');
            app.BEditField_5.Limits = [0 1];
            app.GEditField_5Label = uilabel(app.SecondNovelFeatureTab);
            app.GEditField_5Label.Text = 'G';
            app.GEditField_5 = uieditfield(app.SecondNovelFeatureTab, 'numeric');
            app.GEditField_5.Limits = [0 1];
            app.GEditField_5.Value = 1;
            app.UIAxes6 = uiaxes(app.SecondNovelFeatureTab);
            title(app.UIAxes6, 'Task 5')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Coursework2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end